classdef lab1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        AlegetifunctiaButtonGroup       matlab.ui.container.ButtonGroup
        predefinitaButton               matlab.ui.control.RadioButton
        interactivButton                matlab.ui.control.RadioButton
        formulaDropDown                 matlab.ui.control.DropDown
        formulaEditField                matlab.ui.control.EditField
        bLabel                          matlab.ui.control.Label
        bEditField                      matlab.ui.control.EditField
        aEditFieldLabel                 matlab.ui.control.Label
        aEditField                      matlab.ui.control.EditField
        SpecificatipreciziaButtonGroup  matlab.ui.container.ButtonGroup
        Label                           matlab.ui.control.Label
        Spinner                         matlab.ui.control.Spinner
        AlegetimetodaButtonGroup        matlab.ui.container.ButtonGroup
        metodabisectieiButton           matlab.ui.control.RadioButton
        metodaNewtonButton              matlab.ui.control.RadioButton
        metodasecantelorButton          matlab.ui.control.RadioButton
        metodacoardelorButton           matlab.ui.control.RadioButton
        UITable                         matlab.ui.control.Table
        CalculeazaButton                matlab.ui.control.Button
        UIAxes                          matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        selectedFunctionType % predefined or interactive
        selectedAlgorithmType % bisection, Newton, etc.
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.selectedFunctionType = app.AlegetifunctiaButtonGroup.SelectedObject;
            app.selectedAlgorithmType = app.metodabisectieiButton;
        end

        % Selection changed function: AlegetifunctiaButtonGroup
        function AlegetifunctiaButtonGroupSelectionChanged(app, event)
            app.selectedFunctionType = app.AlegetifunctiaButtonGroup.SelectedObject;
            
            if app.selectedFunctionType == app.predefinitaButton
                app.aEditField.Enable = false;
                app.bEditField.Enable = false;
                app.formulaEditField.Enable = false;
                app.formulaDropDown.Enable = true;
            else
                app.aEditField.Enable = true;
                app.bEditField.Enable = true;
                app.formulaEditField.Enable = true;
                app.formulaDropDown.Enable = false;
            end
        end

        % Selection changed function: AlegetimetodaButtonGroup
        function AlegetimetodaButtonGroupSelectionChanged(app, event)
            app.selectedAlgorithmType = app.AlegetimetodaButtonGroup.SelectedObject;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.AlegetifunctiaButtonGroup = uibuttongroup(app.UIFigure);
            app.AlegetifunctiaButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @AlegetifunctiaButtonGroupSelectionChanged, true);
            app.AlegetifunctiaButtonGroup.Title = '1) Alegeti functia';
            app.predefinitaButton = uiradiobutton(app.AlegetifunctiaButtonGroup);
            app.predefinitaButton.Text = 'predefinita';
            app.predefinitaButton.Value = true;
            app.interactivButton = uiradiobutton(app.AlegetifunctiaButtonGroup);
            app.interactivButton.Text = 'interactiv';
            app.formulaDropDown = uidropdown(app.AlegetifunctiaButtonGroup);
            app.formulaDropDown.Editable = 'on';
            app.formulaEditField = uieditfield(app.AlegetifunctiaButtonGroup, 'text');
            app.formulaEditField.Enable = 'off';
            app.bLabel = uilabel(app.AlegetifunctiaButtonGroup);
            app.bLabel.Enable = 'off';
            app.bLabel.Text = 'b ';
            app.bEditField = uieditfield(app.AlegetifunctiaButtonGroup, 'text');
            app.bEditField.Enable = 'off';
            app.aEditFieldLabel = uilabel(app.AlegetifunctiaButtonGroup);
            app.aEditFieldLabel.Enable = 'off';
            app.aEditFieldLabel.Text = 'a';
            app.aEditField = uieditfield(app.AlegetifunctiaButtonGroup, 'text');
            app.aEditField.Enable = 'off';
            app.SpecificatipreciziaButtonGroup = uibuttongroup(app.UIFigure);
            app.SpecificatipreciziaButtonGroup.Title = '2) Specificati precizia';
            app.Label = uilabel(app.SpecificatipreciziaButtonGroup);
            app.Label.Text = '10 ^';
            app.Spinner = uispinner(app.SpecificatipreciziaButtonGroup);
            app.Spinner.Limits = [-10 -5];
            app.Spinner.Value = -5;
            app.AlegetimetodaButtonGroup = uibuttongroup(app.UIFigure);
            app.AlegetimetodaButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @AlegetimetodaButtonGroupSelectionChanged, true);
            app.AlegetimetodaButtonGroup.Title = '3) Alegeti metoda';
            app.metodabisectieiButton = uiradiobutton(app.AlegetimetodaButtonGroup);
            app.metodabisectieiButton.Text = 'metoda bisectiei';
            app.metodabisectieiButton.Value = true;
            app.metodaNewtonButton = uiradiobutton(app.AlegetimetodaButtonGroup);
            app.metodaNewtonButton.Text = 'metoda Newton';
            app.metodasecantelorButton = uiradiobutton(app.AlegetimetodaButtonGroup);
            app.metodasecantelorButton.Text = 'metoda secantelor';
            app.metodacoardelorButton = uiradiobutton(app.AlegetimetodaButtonGroup);
            app.metodacoardelorButton.Text = 'metoda coardelor';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable.RowName = {};
            app.CalculeazaButton = uibutton(app.UIFigure, 'push');
            app.CalculeazaButton.Text = 'Calculeaza';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = lab1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end